classdef APP_simulink_2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        PrincipaleTab                   matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        Tensions_thermistanceTab        matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        UIAxesV                         matlab.ui.control.UIAxes
        Rsistances_thermistanceTab      matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        UIAxesR                         matlab.ui.control.UIAxes
        EstimationTab                   matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        UIAxesESTIMT3                   matlab.ui.control.UIAxes
        ADCTab                          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxesADC                       matlab.ui.control.UIAxes
        autresignauxTab                 matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxesTEC_out                   matlab.ui.control.UIAxes
        UIAxesTEC_in                    matlab.ui.control.UIAxes
        UIAxesAMP_in                    matlab.ui.control.UIAxes
        UIAxesPWM                       matlab.ui.control.UIAxes
        ParamtreavancsTab               matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SimulationduPWMPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Switch                          matlab.ui.control.Switch
        FonctiondetransfertdelaperturbationsurlaplaquePanel  matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        T3EditField_10                  matlab.ui.control.NumericEditField
        T3EditField_9                   matlab.ui.control.NumericEditField
        T3EditField_8                   matlab.ui.control.NumericEditField
        T3EditField_7                   matlab.ui.control.NumericEditField
        T3EditFieldLabel_2              matlab.ui.control.Label
        T2EditFieldLabel_2              matlab.ui.control.Label
        T1EditFieldLabel_9              matlab.ui.control.Label
        T1EditFieldLabel_8              matlab.ui.control.Label
        T1EditFieldLabel_7              matlab.ui.control.Label
        T1EditFieldLabel_6              matlab.ui.control.Label
        T1EditField_2                   matlab.ui.control.NumericEditField
        TdEditField_3                   matlab.ui.control.NumericEditField
        TfEditField_3                   matlab.ui.control.NumericEditField
        T3EditField_6                   matlab.ui.control.NumericEditField
        T2EditField_2                   matlab.ui.control.NumericEditField
        TensiondestaurationLabel        matlab.ui.control.Label
        TensiondesaturationSlider       matlab.ui.control.Slider
        FonctiondetransfertdelaplaqueParrapportauTECPanel  matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        T3EditField_5                   matlab.ui.control.NumericEditField
        T3EditField_4                   matlab.ui.control.NumericEditField
        T3EditField_3                   matlab.ui.control.NumericEditField
        T3EditField_2                   matlab.ui.control.NumericEditField
        T3EditFieldLabel                matlab.ui.control.Label
        T2EditFieldLabel                matlab.ui.control.Label
        T1EditFieldLabel_5              matlab.ui.control.Label
        T1EditFieldLabel_4              matlab.ui.control.Label
        T1EditFieldLabel_3              matlab.ui.control.Label
        T1EditFieldLabel_2              matlab.ui.control.Label
        T1EditField                     matlab.ui.control.NumericEditField
        TdEditField_2                   matlab.ui.control.NumericEditField
        TfEditField_2                   matlab.ui.control.NumericEditField
        T3EditField                     matlab.ui.control.NumericEditField
        T2EditField                     matlab.ui.control.NumericEditField
        EnattenteLabel                  matlab.ui.control.Label
        DmarrerButton                   matlab.ui.control.Button
        SauvegarderButton               matlab.ui.control.Button
        PerturbationPanel               matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        Label_7                         matlab.ui.control.Label
        TempsdechangementsEditField_2   matlab.ui.control.NumericEditField
        TempsdechangementsEditField_2Label  matlab.ui.control.Label
        PuissancefinaleEditField_2      matlab.ui.control.NumericEditField
        PuissancefinaleEditField_2Label  matlab.ui.control.Label
        PuissanceinitialeEditField      matlab.ui.control.NumericEditField
        PuissancefinaleEditFieldLabel   matlab.ui.control.Label
        AppliquerparamtresButton        matlab.ui.control.Button
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        RgulateurPIDFPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        TfEditField                     matlab.ui.control.NumericEditField
        TfEditFieldLabel                matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        KcEditField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        ConsignesdetempratureCPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        TempsdechangementsEditField     matlab.ui.control.NumericEditField
        TempsdechangementsEditFieldLabel  matlab.ui.control.Label
        FinaleEditField                 matlab.ui.control.NumericEditField
        FinaleEditFieldLabel            matlab.ui.control.Label
        InitialeEditField               matlab.ui.control.NumericEditField
        InitialeEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        %simulation; % Descriptio
        myscope;
        Ts = 5;
        precons = 25;
        matrice;
        consigne = [];
        commande;
        increment = 0;
        time;
        data;
        time2;
        data2_1 ;
        data2_2;
        data2_3 ;
        data2_4 ;
        model = "MAIN_SIMULINK_2023a_";
        model_perturb ="MAIN_SIMULINK_2023a/Puissance de perturbation";
    end
    
    methods (Access = public)
        
        function AppliquerButtonPushedCallback(app,event)
            AppliquerButtonPushed(app,event);
        end
        function ArrterButtonPushedCallback(app, event)
            AppliquerparamtresButtonPushed(app,event);
        end
        function DmarrerButtonPushedCallback(app, event)
            DmarrerButtonPushed(app,event);
        end
        function StartStopButtonSizeChangedCallback(app, event)
        StartStopButtonSizeChanged(app, event);
        end
        function default_params(app)
            addpath("Default_identification")

            % Assigner les ft de la plaque
            gp_1 = load('T1_default.mat').gp_1;
            assignin('base', 'gp_1', gp_1);

            gp_2 = load('T2_default.mat').gp_2;
            assignin('base', 'gp_2', gp_2);

            gp_3 = load('T3_default.mat').gp_3;
            assignin('base', 'gp_3', gp_3);

            % Assigner les ft_z pour l'estimation
            Z_T3 = load('Z_T3_default.mat').Z_T3;
            assignin('base', 'Z_T3', Z_T3);
            
            Z_T1T3 = load('Z_T1T3_default.mat').Z_T1T3;
            assignin('base', 'Z_T1T3', Z_T1T3);

            % Assigner les ft des perturbation
            gpp_t1 = load('pert_T1_default.mat').gpp_t1;
            assignin('base', 'gpp_t1', gpp_t1);
            
            gpp_t2 = load('pert_T2_default.mat').gpp_t2;
            assignin('base', 'gpp_t2', gpp_t2);

            gpp_t3 = load('pert_T3_default.mat').gpp_t3;
            assignin('base', 'gpp_t3', gpp_t3);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        load_system(app.model);
        default_params(app)
        AppliquerparamtresButtonPushed(app, 1);
        end

        % Button pushed function: DmarrerButton
        function DmarrerButtonPushed(app, event)
            % Démarrer la simulation Simulink
            set_param(app.model, 'SimulationCommand', 'update');  % Appliquer les changements
            set_param(app.model, "SimulationCommand", "start");
            try
            % Mettre à jour l'étiquette pour indiquer que la simulation a démarré
            app.EnattenteLabel.Text = "Simulation démarrée avec succès :) .....";
            cla(app.UIAxes);
            cla(app.UIAxes2);
            %app.EnattenteLabel.Text = "Veuillez patienter quelques secondes......... :)";
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Commande pour ScopeData1 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               while strcmp(get_param(app.model, 'SimulationStatus'), 'running')
                    pause(0.1);  % Petite pause pour éviter une boucle trop intensive
                end
            % Récupérer les données de ScopeData1 depuis le workspace
            ScopeData1 = evalin('base', 'ScopeData1');  % Données du scope 1
            
            app.time = ScopeData1(:, 1);  
            app.data = ScopeData1(:, 2); 
            
            % Effacer l'axe avant de redessiner la courbe pour UIAxes1
            plot(app.UIAxes, app.time, app.data, 'DisplayName', 'Signal ScopeData1');
            title(app.UIAxes, 'Commande u (ScopeData1)');
            xlabel(app.UIAxes, 'Temps');
            ylabel(app.UIAxes, 'u [v]');
            grid(app.UIAxes, 'on');
            
            drawnow;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Commande pour ScopeData2 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Récupérer les données de ScopeData2 depuis le workspace
            ScopeData2 = evalin('base', 'ScopeData2');  % Données du scope 2
            % Extraction des données de temps et des signaux pour ScopeData2
            app.time2 = ScopeData2(:, 1);     % Première colonne = Temps
            app.data2_1 = ScopeData2(:, 2);   % Deuxième colonne = Signal 1
            app.data2_2 = ScopeData2(:, 3);   % Troisième colonne = Signal 2
            app.data2_3 = ScopeData2(:, 4);   % Quatrième colonne = Signal 3
            app.data2_4 = ScopeData2(:, 5);   % Cinquième colonne = Signal 4
                
            
            % Effacer l'axe avant de redessiner la courbe pour UIAxes2  % 'reset' réinitialise l'axe complètement
            hold(app.UIAxes2, 'on');  % Garder le même graphique pour ajouter les courbes suivantes
            % Tracer les 4 signaux de ScopeData2 sur l'UIAxes2
            plot(app.UIAxes2, app.time2, app.data2_1, 'DisplayName', 'T3 (ScopeData2)');
            plot(app.UIAxes2, app.time2, app.data2_2, 'DisplayName', 'T2 (ScopeData2)');
            plot(app.UIAxes2, app.time2, app.data2_3, 'DisplayName', 'T1 (ScopeData2)');
            plot(app.UIAxes2, app.time2, app.data2_4, 'DisplayName', 'Consigne(r)');
            
            % Ajouter une légende pour identifier les signaux
            legend(app.UIAxes2);
            
            % Ajouter un titre et des labels aux axes de UIAxes2
            title(app.UIAxes2, 'Signaux (ScopeData2)');
            xlabel(app.UIAxes2, 'Temps');
            ylabel(app.UIAxes2, 'Amplitude');
            grid(app.UIAxes2, 'on');
            
            % Relâcher le maintien de la courbe pour d'autres tracés si nécessaire
            hold(app.UIAxes2, 'off');
            
            % Forcer le rafraîchissement de l'interface graphique
            drawnow;
            
            
            catch ME
            
               uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end
        end

        % Button pushed function: AppliquerparamtresButton
        function AppliquerparamtresButtonPushed(app, event)
            try
            app.EnattenteLabel.Text ="Envoie des parametres .....";
            Ts1 = 5;
            assignin("base","f",1/Ts1);

            % Calcul des ft pour le régulateur
            DF      = tf([app.TdEditField.Value,1],[app.TfEditField.Value,1]);
            TI      = tf(1,[app.TiEditField.Value,1]);
            DFz     = c2d(DF,Ts1,"tustin");
            TIz     = c2d(TI,Ts1,"zoh");

            % Assigner les variable pour le régulateur
            assignin("base","Tiz",TIz);
            assignin("base","DFz",DFz);
            assignin("base","kc",app.KcEditField.Value);

            % Assigner les variable pour la consigne
            assignin("base","consigne_step_time",app.TempsdechangementsEditField.Value);
            assignin("base","consigne_init",app.InitialeEditField.Value);
            assignin("base","consigne_finale",app.FinaleEditField.Value);
            
            % Assigner les variable pour la perturbation
            assignin("base", "pert_finale", app.PuissancefinaleEditField_2.Value);
            assignin("base", "pert_init", app.PuissanceinitialeEditField.Value);
            assignin("base", "pert_step_time", app.TempsdechangementsEditField_2.Value);
            
            set_param(app.model,"StopTime",num2str(app.DuredesimulationEditField.Value));
            set_param(app.model, 'SimulationCommand', 'update');
           
            pause(2); % Pourquoi?
            app.EnattenteLabel.Text ="Envoie des paramtres avec succes :) .....";
            catch ME
                %disp("Erreur detetee");
                uialert(app.UIFigure,"Erreur :" + ME.message,"Erreur");
            end

        end

        % Callback function
        function ArrterButtonPushed(app, event)
            set_param(app.model,"SimulationCommand","stop");
            pause(0.5);
            app.EnattenteLabel.Text ="Simulation arreté avec succes :) .....";
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            % fixer un intervalle de temps selon le temps d'echantillonnage:
           app.increment = app.increment +1 ;
           %% chaque rangee contient dans l'odre : temps - consigne - commande(tension) - t1 - t2 - t3 - t3 estimer
           app.EnattenteLabel.Text = 'En attente de sauvegarde......';
           indices = mod(app.time2, 5) == 0;
           matrice_filtre = [app.time2(indices), app.data2_4(indices), app.data(indices), app.data2_2(indices), app.data2_3(indices), app.data2_1(indices)];
           % convertir en tableau puis leur donnée un nom
           T = array2table(matrice_filtre);
           T.Properties.VariableNames = ["Temps", "Consigne", "Commande", "T1", "T2", "T3"];
           pause(0.5);
           app.EnattenteLabel.Text = 'Données sauvegardées !';
           %% convertir en excel file
           %xlswrite('test.xlsx',app.matrice);
           formatSpec = 'Test%d.xlsx';
           writetable(T, sprintf(formatSpec,app.increment));
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(value)
            if value == 'Sans'
                disp(app.model + '/PWM');
                set_param(app.model + "/PWM", 'Commented', 'through');
                set_param(app.model + '/Conditionnement de sortie', 'Commented', 'through');
                set_param(app.model + '/Filtre trible RC', 'Commented', 'through');
            else
                disp([app.model 'PWM']);
                set_param(app.model+ "/PWM", 'Commented', 'off');
                set_param(app.model+ '/Conditionnement de sortie', 'Commented', 'off');
                set_param(app.model+ '/Filtre trible RC', 'Commented', 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConsignesdetempratureCPanel = uipanel(app.GridLayout);
            app.ConsignesdetempratureCPanel.Title = 'Consigne(s) de température [C]';
            app.GridLayout2 = uigridlayout(app.ConsignesdetempratureCPanel);
            app.InitialeEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialeEditFieldLabel.Text = 'Initiale';
            app.InitialeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InitialeEditField.Value = 30;
            app.FinaleEditFieldLabel = uilabel(app.GridLayout2);
            app.FinaleEditFieldLabel.Text = 'Finale';
            app.FinaleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FinaleEditField.Value = 20;
            app.TempsdechangementsEditFieldLabel = uilabel(app.GridLayout2);
            app.TempsdechangementsEditFieldLabel.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TempsdechangementsEditField.Value = 500;
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = 'ⓘ';
            app.RgulateurPIDFPanel = uipanel(app.GridLayout);
            app.RgulateurPIDFPanel.Title = 'Régulateur PIDF';
            app.GridLayout3 = uigridlayout(app.RgulateurPIDFPanel);
            app.KcEditFieldLabel = uilabel(app.GridLayout3);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KcEditField.Value = 0.155;
            app.TiEditFieldLabel = uilabel(app.GridLayout3);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TiEditField.Value = 145;
            app.TfEditFieldLabel = uilabel(app.GridLayout3);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TfEditField.Value = 4.33;
            app.TdEditFieldLabel = uilabel(app.GridLayout3);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TdEditField.Value = 13;
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'ⓘ';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = 'ⓘ';
            app.Label_5 = uilabel(app.GridLayout3);
            app.Label_5.Text = 'ⓘ';
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Text = 'ⓘ';
            app.DuredesimulationEditFieldLabel = uilabel(app.GridLayout);
            app.DuredesimulationEditFieldLabel.Text = 'Durée de simulation';
            app.DuredesimulationEditField = uieditfield(app.GridLayout, 'numeric');
            app.DuredesimulationEditField.Value = 1000;
            app.AppliquerparamtresButton = uibutton(app.GridLayout, 'push');
            app.AppliquerparamtresButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerparamtresButtonPushed, true);
            app.AppliquerparamtresButton.Text = 'Appliquer paramètres';
            app.PerturbationPanel = uipanel(app.GridLayout);
            app.PerturbationPanel.Title = 'Perturbation';
            app.GridLayout2_2 = uigridlayout(app.PerturbationPanel);
            app.PuissancefinaleEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PuissancefinaleEditFieldLabel.Text = 'Puissance finale';
            app.PuissanceinitialeEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PuissancefinaleEditField_2Label = uilabel(app.GridLayout2_2);
            app.PuissancefinaleEditField_2Label.Text = 'Puissance finale';
            app.PuissancefinaleEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.TempsdechangementsEditField_2Label = uilabel(app.GridLayout2_2);
            app.TempsdechangementsEditField_2Label.Text = 'Temps de changement [s]';
            app.TempsdechangementsEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Text = 'ⓘ';
            app.SauvegarderButton = uibutton(app.GridLayout, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.DmarrerButton = uibutton(app.GridLayout, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerButtonPushed, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.EnattenteLabel = uilabel(app.GridLayout);
            app.EnattenteLabel.Text = 'En attente...';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PrincipaleTab = uitab(app.TabGroup);
            app.PrincipaleTab.Title = 'Principale';
            app.GridLayout6 = uigridlayout(app.PrincipaleTab);
            app.UIAxes2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2, 'Évolution de la consigne et des température de T1, T2 et T3')
            xlabel(app.UIAxes2, 'Temps[s]')
            ylabel(app.UIAxes2, 'Température [ C]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Évolution de la commande ')
            xlabel(app.UIAxes, 'Temps[s]')
            ylabel(app.UIAxes, 'Commande [V]')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.Tensions_thermistanceTab = uitab(app.TabGroup);
            app.Tensions_thermistanceTab.Title = 'Tensions_thermistance';
            app.GridLayout10 = uigridlayout(app.Tensions_thermistanceTab);
            app.UIAxesV = uiaxes(app.GridLayout10);
            title(app.UIAxesV, 'Tension à T1, T2 et T3')
            xlabel(app.UIAxesV, 'X')
            ylabel(app.UIAxesV, 'Y')
            zlabel(app.UIAxesV, 'Z')
            app.Rsistances_thermistanceTab = uitab(app.TabGroup);
            app.Rsistances_thermistanceTab.Title = 'Résistances_thermistance';
            app.GridLayout9 = uigridlayout(app.Rsistances_thermistanceTab);
            app.UIAxesR = uiaxes(app.GridLayout9);
            title(app.UIAxesR, 'Résistance de T1, T2 et T3')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.EstimationTab = uitab(app.TabGroup);
            app.EstimationTab.Title = 'Estimation';
            app.GridLayout8 = uigridlayout(app.EstimationTab);
            app.UIAxesESTIMT3 = uiaxes(app.GridLayout8);
            title(app.UIAxesESTIMT3, 'T3 mesuré avec T3 estimé  et l''écart')
            xlabel(app.UIAxesESTIMT3, 'X')
            ylabel(app.UIAxesESTIMT3, 'Y')
            zlabel(app.UIAxesESTIMT3, 'Z')
            app.ADCTab = uitab(app.TabGroup);
            app.ADCTab.Title = 'ADC';
            app.GridLayout7 = uigridlayout(app.ADCTab);
            app.UIAxesADC = uiaxes(app.GridLayout7);
            title(app.UIAxesADC, 'Sortie des ADC pour T1 T2 T3')
            xlabel(app.UIAxesADC, 'X')
            ylabel(app.UIAxesADC, 'Y')
            zlabel(app.UIAxesADC, 'Z')
            app.autresignauxTab = uitab(app.TabGroup);
            app.autresignauxTab.Title = 'autre signaux';
            app.GridLayout5 = uigridlayout(app.autresignauxTab);
            app.UIAxesPWM = uiaxes(app.GridLayout5);
            title(app.UIAxesPWM, 'Sortie PWM')
            xlabel(app.UIAxesPWM, 'X')
            ylabel(app.UIAxesPWM, 'Y')
            zlabel(app.UIAxesPWM, 'Z')
            app.UIAxesAMP_in = uiaxes(app.GridLayout5);
            title(app.UIAxesAMP_in, 'Entré amplificateur')
            xlabel(app.UIAxesAMP_in, 'X')
            ylabel(app.UIAxesAMP_in, 'Y')
            zlabel(app.UIAxesAMP_in, 'Z')
            app.UIAxesTEC_in = uiaxes(app.GridLayout5);
            title(app.UIAxesTEC_in, 'Entré TEC')
            xlabel(app.UIAxesTEC_in, 'X')
            ylabel(app.UIAxesTEC_in, 'Y')
            zlabel(app.UIAxesTEC_in, 'Z')
            app.UIAxesTEC_out = uiaxes(app.GridLayout5);
            title(app.UIAxesTEC_out, 'Sortie TEC')
            xlabel(app.UIAxesTEC_out, 'X')
            ylabel(app.UIAxesTEC_out, 'Y')
            zlabel(app.UIAxesTEC_out, 'Z')
            app.ParamtreavancsTab = uitab(app.TabGroup);
            app.ParamtreavancsTab.Title = 'Paramètre avancés';
            app.GridLayout11 = uigridlayout(app.ParamtreavancsTab);
            app.FonctiondetransfertdelaplaqueParrapportauTECPanel = uipanel(app.GridLayout11);
            app.FonctiondetransfertdelaplaqueParrapportauTECPanel.Title = 'Fonction de transfert de la plaque (Par rapport au TEC)';
            app.GridLayout3_2 = uigridlayout(app.FonctiondetransfertdelaplaqueParrapportauTECPanel);
            app.T2EditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.T2EditField.Value = 0.155;
            app.T3EditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.T3EditField.Value = 145;
            app.TfEditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.TfEditField_2.Value = 4.33;
            app.TdEditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.TdEditField_2.Value = 13;
            app.T1EditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.T1EditField.Value = 0.155;
            app.T1EditFieldLabel_2 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_2.Text = 'Gain statique';
            app.T1EditFieldLabel_3 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_3.Text = 'Constante de temps';
            app.T1EditFieldLabel_4 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_4.Text = 'Retard';
            app.T1EditFieldLabel_5 = uilabel(app.GridLayout3_2);
            app.T1EditFieldLabel_5.Text = 'T1';
            app.T2EditFieldLabel = uilabel(app.GridLayout3_2);
            app.T2EditFieldLabel.Text = 'T2';
            app.T3EditFieldLabel = uilabel(app.GridLayout3_2);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.T3EditField_2.Value = 145;
            app.T3EditField_3 = uieditfield(app.GridLayout3_2, 'numeric');
            app.T3EditField_4 = uieditfield(app.GridLayout3_2, 'numeric');
            app.T3EditField_4.Value = 5;
            app.T3EditField_5 = uieditfield(app.GridLayout3_2, 'numeric');
            app.T3EditField_5.Value = 15;
            app.TensiondesaturationSlider = uislider(app.GridLayout11);
            app.TensiondesaturationSlider.Limits = [-5 5];
            app.TensiondestaurationLabel = uilabel(app.GridLayout11);
            app.TensiondestaurationLabel.Text = 'Tension de stauration';
            app.FonctiondetransfertdelaperturbationsurlaplaquePanel = uipanel(app.GridLayout11);
            app.FonctiondetransfertdelaperturbationsurlaplaquePanel.Title = 'Fonction de transfert de la perturbation sur la plaque';
            app.GridLayout3_3 = uigridlayout(app.FonctiondetransfertdelaperturbationsurlaplaquePanel);
            app.T2EditField_2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T2EditField_2.Value = 0.155;
            app.T3EditField_6 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T3EditField_6.Value = 145;
            app.TfEditField_3 = uieditfield(app.GridLayout3_3, 'numeric');
            app.TfEditField_3.Value = 4.33;
            app.TdEditField_3 = uieditfield(app.GridLayout3_3, 'numeric');
            app.TdEditField_3.Value = 13;
            app.T1EditField_2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T1EditField_2.Value = 0.155;
            app.T1EditFieldLabel_6 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_6.Text = 'Gain statique';
            app.T1EditFieldLabel_7 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_7.Text = 'Constante de temps';
            app.T1EditFieldLabel_8 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_8.Text = 'Retard';
            app.T1EditFieldLabel_9 = uilabel(app.GridLayout3_3);
            app.T1EditFieldLabel_9.Text = 'T1';
            app.T2EditFieldLabel_2 = uilabel(app.GridLayout3_3);
            app.T2EditFieldLabel_2.Text = 'T2';
            app.T3EditFieldLabel_2 = uilabel(app.GridLayout3_3);
            app.T3EditFieldLabel_2.Text = 'T3';
            app.T3EditField_7 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T3EditField_7.Value = 145;
            app.T3EditField_8 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T3EditField_9 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T3EditField_9.Value = 5;
            app.T3EditField_10 = uieditfield(app.GridLayout3_3, 'numeric');
            app.T3EditField_10.Value = 15;
            app.SimulationduPWMPanel = uipanel(app.GridLayout11);
            app.SimulationduPWMPanel.Title = 'Simulation du PWM';
            app.GridLayout4 = uigridlayout(app.SimulationduPWMPanel);
            app.Switch = uiswitch(app.GridLayout4, 'slider');
            app.Switch.Items = {'Sans', 'Avec'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Sans';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_simulink_2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end